classdef QHY600App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar2                        matlab.ui.container.Toolbar
        PushTool2                       matlab.ui.container.toolbar.PushTool
        CameraParametersPanel           matlab.ui.container.Panel
        GainEditField                   matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        SetTempEditField                matlab.ui.control.NumericEditField
        SetTempLabel                    matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        ExposureusEditField             matlab.ui.control.NumericEditField
        ExposureusEditFieldLabel        matlab.ui.control.Label
        DebugButton                     matlab.ui.control.Button
        FrameTakingStatusTextArea       matlab.ui.control.TextArea
        FrameTakingStatusTextAreaLabel  matlab.ui.control.Label
        CurrentTempTextArea             matlab.ui.control.TextArea
        CurrentTempTextAreaLabel        matlab.ui.control.Label
        ResetTempButton                 matlab.ui.control.Button
        ExportTempButton                matlab.ui.control.Button
        ReadoutModeDropDown             matlab.ui.control.DropDown
        ReadoutModeDropDownLabel        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SetRegionofInterestTab          matlab.ui.container.Tab
        HeightStartingPositionEditField  matlab.ui.control.NumericEditField
        HeightStartingPositionEditFieldLabel  matlab.ui.control.Label
        SetROIButton                    matlab.ui.control.Button
        HeightofROIEditField            matlab.ui.control.NumericEditField
        HeightofROIEditFieldLabel       matlab.ui.control.Label
        WidthofROIEditField_2           matlab.ui.control.NumericEditField
        WidthofROIEditField_2Label      matlab.ui.control.Label
        WidthStartingPostionEditField   matlab.ui.control.NumericEditField
        WidthStartingPostionEditFieldLabel  matlab.ui.control.Label
        RegionOfInterestSetterROILabel  matlab.ui.control.Label
        SingleFrameTab                  matlab.ui.container.Tab
        GetSingleFrameButton            matlab.ui.control.Button
        LiveModeTab                     matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        CaptureButton                   matlab.ui.control.Button
        ImageSavingOptionDropDown       matlab.ui.control.DropDown
        ImageSavingOptionDropDownLabel  matlab.ui.control.Label
        NumofFrameEditField             matlab.ui.control.NumericEditField
        NumofFrameLabel                 matlab.ui.control.Label
        DynamicParaModeTab              matlab.ui.container.Tab
        ImageSavingOptionDropDown_2     matlab.ui.control.DropDown
        ImageSavingOptionDropDown_2Label  matlab.ui.control.Label
        CaptureButton_2                 matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        GainTab                         matlab.ui.container.Tab
        NumberofMeasEditField           matlab.ui.control.NumericEditField
        NumberofMeasEditFieldLabel      matlab.ui.control.Label
        StepsEditField                  matlab.ui.control.NumericEditField
        StepsEditFieldLabel             matlab.ui.control.Label
        StartValueEditField             matlab.ui.control.NumericEditField
        StartValueEditFieldLabel        matlab.ui.control.Label
        ExpTimeTab                      matlab.ui.container.Tab
        NumberofMeasEditField_3         matlab.ui.control.NumericEditField
        NumberofMeasEditField_3Label    matlab.ui.control.Label
        StepsEditField_2                matlab.ui.control.NumericEditField
        StepsEditField_2Label           matlab.ui.control.Label
        StartValueusEditField           matlab.ui.control.NumericEditField
        StartValueusEditFieldLabel      matlab.ui.control.Label
        Tab                             matlab.ui.container.Tab
        NumofFrameperParaEditField      matlab.ui.control.NumericEditField
        NumofFrameperParaEditFieldLabel  matlab.ui.control.Label
        ResetSettingsTab                matlab.ui.container.Tab
        ResetallSettingstoDefaultButton  matlab.ui.control.Button
        ConnectionStatusTextArea        matlab.ui.control.TextArea
        ConnectionStatusTextAreaLabel   matlab.ui.control.Label
        ConnectedLamp                   matlab.ui.control.Lamp
        ConnectedLampLabel              matlab.ui.control.Label
        DisconnectfromCameraButton      matlab.ui.control.Button
        ConnecttoCameraButton           matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        camID; % Description
        ret;
        tempLine;
        connected = false;
        temp;
        all_temp = [];
        all_time_temp = [];
        temp_starting_time;
        livesavingmode = 1;
        dynamsavingmode = 1;
    end
    
    methods (Access = private)
        
        function results = DisplaySpectrum(app, frame)

                %% Realigned spectro 2024-08-16, T=-7C, texp=40s, Ext Fullwell Mode, Gain 26
                %Rstack=fitsread("Stack_16bits_5frames_200s.fits");
                %Rsing=fitsread(frame);
                %imshow(frame);
                Rsing = frame;

                %% Rotating frame
                %Rstack=imrotate(Rstack,-0.15,'crop');
                Rsing=imrotate(Rsing,-0.15,'crop');

                %% Median filtering over 3x3 region
                %Rstack=nanmedfilt2(Rstack,3);
                %Rsing=nanmedfilt2(Rsing,3);

                %% Spectrum average - Background
                %Ramanstack=mean(Rstack(560:565,:))-mean(Rstack(540:555,:));
                Ramansing=mean(Rsing(560:565,:))-mean(Rsing(540:555,:));

                %% Spectra plots
                % hold on
                %plot(Ramansing,'.-')
                plot(app.UIAxes2,Ramansing);
                %plot(Ramanstack,'k.-')
                % @@@@@@@@@@@ %

                %  % create a figure
                % axh = axes('Parent',fgh); % create axes
                % X = 0:0.1:pi;
                % Y = sin(X);
                % lnh = plot(axh,X,Y); % plot in those axes
                % pause(0.1)
                % set(lnh,'XData',[0,1,2],'YData',[1,2,1]) % change the line data

                % realignedspectro
                %pause(0.1) % 0.1 second long ticks for optimization

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConnectedLamp.Enable ='off';
            app.DisconnectfromCameraButton.Enable = 'off';
            app.GetSingleFrameButton.Enable = 'off';
            app.FrameTakingStatusTextArea.Placeholder = 'No Frame Taking yet!';
            %global 1;
            %1 = 1; % this is bad, use a #define instead
            %define 1 1;

        end

        % Button pushed function: ConnecttoCameraButton
        function ConnecttoCameraButtonPushed(app, event)

            [app.ret, app.camID] = QHY_1_SearchCamera();
            if(app.ret == 0)
                
                if app.ReadoutModeDropDown.Value =='Photographic'
                    app.ret = QHY_2_SetReadMode(app.camID,0);
                elseif app.ReadoutModeDropDown.Value =='High Gain'
                    app.ret = QHY_2_SetReadMode(app.camID,1);
                elseif app.ReadoutModeDropDown.Value =='Fullwell'
                    app.ret = QHY_2_SetReadMode(app.camID,0);
                elseif app.ReadoutModeDropDown.Value =='Ext. Fullwell - 2CMS'
                    app.ret = QHY_2_SetReadMode(app.camID,0);
                elseif not(app.ret ==0)
                    app.ConnectionStatusTextArea.Placeholder = 'Error setting Readout Mode';
                end


                if(app.ret == 0)
                    app.ret = QHY_3_SetStreamMode(app.camID, 0.0);
                    if(app.ret == 0)
                        app.ret = QHY_4_InitCamera(app.camID);
                        if(app.ret == 0)
                            app.ret = QHY_5_SetFormat(app.camID, 'RAW16');
                            if(app.ret == 0)
                                app.ret = QHY_5_SetBIN(app.camID, '1X1');
                                if(app.ret == 0)
                                    fprintf("ret = %d\n", app.ret);
                                    app.ConnectedLamp.Enable = 'on';
                                    app.ConnectionStatusTextArea.Placeholder = '';
                                    app.ConnecttoCameraButton.Enable = 'off';
                                    app.DisconnectfromCameraButton.Enable = 'on';
                                    app.GetSingleFrameButton.Enable = 'on';
                                    app.connected = true;

                                end
                            end
                        end
                    end
                end
            else
                app.ConnectionStatusTextArea.Placeholder = 'Connection Error, make sure the USB is connected';
            end
            
            %start recording live temperature
            app.tempLine = animatedline(app.UIAxes);

            ax = gca;
            ax.YGrid = 'on';
            ax.YLim = [-20,20];

            app.temp_starting_time = datetime('now');
            cont = app.connected;
            while cont
                [app.ret,app.temp] = QHY_7_GetTemperature(app.camID);
                t = datetime('now') - app.temp_starting_time;
                app.all_temp = [app.all_temp string(app.temp)];
                app.all_time_temp = [app.all_time_temp string(datetime('now'))];
                
                addpoints(app.tempLine, datenum(t),app.temp);

                ax.XLim = datenum([t-seconds(30) t]);
                datetick('x','keeplimits');

                drawnow;
                
                cont = app.connected;

                app.CurrentTempTextArea.Placeholder = strcat(string(app.temp),' C°');
                
            end

        
        end

        % Button pushed function: DisconnectfromCameraButton
        function DisconnectfromCameraButtonPushed(app, event)


            if(app.ret == 0)
                app.ret = QHY_11_CloseCamera(app.camID);
                fprintf("Close Camera ret = %d\n", app.ret);
                app.ConnectedLamp.Enable = 'off';
                app.ConnecttoCameraButton.Enable = 'on';
                app.DisconnectfromCameraButton.Enable = 'off';
                app.GetSingleFrameButton.Enable = 'off';
                app.connected = false;
            end

        end

        % Button pushed function: GetSingleFrameButton
        function GetSingleFrameButtonPushed(app, event)


            if(app.ret == 0)
                app.FrameTakingStatusTextArea.Placeholder = 'In Taking...';
                app.ret = QHY_6_SetExposeTime(app.camID,app.ExposureusEditField.Value);
                fprintf("Expose Time ret = %d\n", app.ret);
                app.ret = QHY_6_SetGain(app.camID, 10.0);
                fprintf("Gain ret = %d\n", app.ret);
                app.ret = QHY_6_SetOffset(app.camID, 10.0);
                fprintf("Offset ret = %d\n", app.ret);
                app.ret = QHY_6_SetDDR(app.camID, 1.0);
                fprintf("DDR ret = %d\n", app.ret);
                app.ret = QHY_9_BeginSingleExpose(app.camID);
                fprintf("Begin single expose ret = %d\n", app.ret);
                [app.ret, w, h, bpp, channels, ImgData] = QHY_9_GetOneSingleFrame(app.camID);
                fprintf("Get Single Frame ret = %d\n", app.ret);
                if(app.ret == 0)


                    %fprintf("w = %d h = %d bpp = %d channels = %d\n This is img data: %d\n", w, h, bpp, channels, ImgData);
                    %get current time
                    t1 = string(datetime('now','Format','yyyy-MM-dd'));
                    t2 = string(datetime('now','Format','HH_mm_ss'));
                    lpath = "C:\Users\User\Documents\QHY600-Capture\SingleCapture";
                    n1path=fullfile(lpath,t1);
                    n2path = fullfile(n1path,t2);
                    mkdir(n1path);
                    mkdir(n2path);
                    txtName = fullfile(n2path,'Capturedata.txt');
                    txtFile = fopen(txtName,'wt');
                    %imshow(ImgData);
                    %imageCheck = fopen(ImgData, "r");
                    %fprintf(size(imageCheck));
                    
                    fprintf(txtFile,'test\n');
                    fclose(txtFile);
                    imName = fullfile(n2path,'Capture.fits');
                    imgData_int16 = int16(ImgData);
                    fitswrite(imgData_int16,imName);
                    
                    
                end
                app.ret = QHY_9_StopSingleCapture(app.camID);
                fprintf("Stop Single Capture ret = %d\n", app.ret);

                img_spectum = mean(imgData_int16);
                plot(app.UIAxes2,img_spectum);
            end
            
            clear QHY_6_SetExposeTime;
            clear QHY_6_SetGain;
            clear QHY_6_SetOffset;
            clear QHY_6_SetDDR;
            clear QHY_9_BeginSingleExpose;
            clear QHY_9_GetOneSingleFrame;
            clear QHY_9_StopSingleCapture;
            app.FrameTakingStatusTextArea.Placeholder = 'Finished!';


        end

        % Value changed function: ExposureusEditField
        function ExposureusEditFieldValueChanged(app, event)
            app.ExposureusEditField.Value;
            app.ret = QHY_6_SetExposeTime(app.camID, app.ExposureusEditField.Value);
            if not(app.ret ==0)
                app.ConnectionStatusTextArea.Placeholder = 'Error setting Exposure Time';
            end
            clear QHY_6_SetExposeTime;

            
        end

        % Callback function
        function GainSliderValueChanged(app, event)
            value = app.GainSlider.Value;
            app.ret = QHY_6_SetGain(app.camID, value);
            if not(app.ret ==0)
                app.ConnectionStatusTextArea.Placeholder = 'Error setting Gain';
            end
            clear QHY_6_SetGain;
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            value = app.OffsetEditField.Value;
            app.ret = QHY_6_SetOffset(app.camID, value);
            if not(app.ret ==0)
                app.ConnectionStatusTextArea.Placeholder = 'Error setting Offset';
            end
            clear QHY_6_SetOffset;
        end

        % Value changed function: SetTempEditField
        function SetTempEditFieldValueChanged(app, event)
            value = app.SetTempEditField.Value;
            app.ret = QHY_6_SetCoolerTemperature(app.camID, value);
            if not(app.ret ==0)
                app.ConnectionStatusTextArea.Placeholder = 'Error setting Cooler Temperature';
            end
            clear QHY_6_SetCoolerTemperature;
        end

        % Value changed function: ReadoutModeDropDown
        function ReadoutModeDropDownValueChanged(app, event)
            value = app.ReadoutModeDropDown.Value;
            if value =='Photographic'
                app.ret = QHY_2_SetReadMode(app.camID,0);
            elseif value =='High Gain'
                app.ret = QHY_2_SetReadMode(app.camID,1);
            elseif value =='Fullwell'
                app.ret = QHY_2_SetReadMode(app.camID,2);
            elseif value =='Ext. Fullwell - 2CMS'
                app.ret = QHY_2_SetReadMode(app.camID,3);
            end
            if not(app.ret ==0)
                app.ConnectionStatusTextArea.Placeholder = 'Error setting Readout Mode';
            end
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)


%%% Helper Functions %%%

            % Acquire live feed using "1" saving method
            function result = UpdateFrame1(frameNum)

                while(frameNum < app.NumofFrameEditField.Value)
                    tic

                    if(QHY_6_SetDDR(app.camID, 1.0) == 1)
                        error("\nERROR: Could not set DDR\n");
                    end

                    [app.ret, w, h, bpp, channels, ImgData] = QHY_10_GetOneLiveFrame(app.camID);
                    if(app.ret == 1)
                        error("\nERROR: Could not get a live frame\n");
                    end


                    % Display Current Frame
                    fprintf("QHY_10_GetOneLiveFrame returned ret = %d w = %d h = %d\n", app.ret, w, h); %info
                    %fprintf("This is image data: %d", ImgData); %print img data
                    %imshow(ImgData)
                    DisplaySpectrum(app, ImgData);

                    % Update frame counter
                    frameNum = frameNum + 1;

                    % Save Frame and relevent info to txtFile
                    txtName = fullfile(n2path,strcat("Capturedata",num2str(frameNum),".txt"));
                    txtFile = fopen(txtName,'wt');

                    fprintf(txtFile,'test\n');
                    fprintf(txtFile, txtName);
                    fclose(txtFile);
                    imName = fullfile(n2path,strcat("Capture",num2str(frameNum),".fits"));
                    fitswrite(int16(ImgData),imName);

                    if frameNum == app.NumofFrameEditField.Value -1
                        result = int16(ImgData);
                    end
                    pause(0.01)

                    toc
                    nameOfPlaceholder = strcat('Frame ', num2str(frameNum +1 ), ' Taken...');
                    app.FrameTakingStatusTextArea.Placeholder = nameOfPlaceholder;
                end %end while
            end 
            %end Function UpdateFrame \\

            
            % Acquire live feed using "2" saving method
            function UpdateFrame2(frameNum)

                while(frameNum < app.NumofFrameEditField.Value)
                    tic

                    if(QHY_6_SetDDR(app.camID, 1.0) == 1)
                        error("\nERROR: Could not set DDR\n");
                    end

                    [app.ret, w, h, bpp, channels, ImgTemp] = QHY_10_GetOneLiveFrame(app.camID);
                    if(app.ret == 1)
                        error("\nERROR: Could not get a live frame\n");
                    end

                    fprintf("QHY_10_GetOneLiveFrame returned ret = %d w = %d h = %d\n", app.ret, w, h);
                    frameNum = frameNum + 1;
                    ImgData(:,:,frameNum) = ImgTemp;
                    %pause(0.01)

                    toc
                    nameOfPlaceholder = strcat('Frame ', num2str(frameNum +1 ), ' Taken...');
                    app.FrameTakingStatusTextArea.Placeholder = nameOfPlaceholder;
                end %end while
            end
            % end Function UpdateFrame2 \\


            % Acquire live feed using "3" saving method
            function UpdateFrame3()

                while(frameNum < app.NumofFrameEditField.Value)
                    tic

                    if(QHY_6_SetDDR(app.camID, 1.0) == 1)
                        error("\nERROR: Could not set DDR\n");
                    end

                    [app.ret, w, h, bpp, channels, ImgTemp] = QHY_10_GetOneLiveFrame(app.camID);
                    if(app.ret == 1)
                        error("\nERROR: Could not get a live frame\n");
                    end

                    fprintf("QHY_10_GetOneLiveFrame returned ret = %d w = %d h = %d\n", app.ret, w, h);
                    frameNum = frameNum + 1;
                    imgWeight = 10*log10(mean(ImgTemp,'all')/std(ImgTemp,0,'all'));
                    ImgData(:,:,frameNum) = ImgTemp*imgWeight;
                    weights(:,frameNum) = imgWeight;
                    pause(0.01)


                    toc
                    nameOfPlaceholder = strcat('Frame ', num2str(frameNum +1 ), ' Taken...');
                    app.FrameTakingStatusTextArea.Placeholder = nameOfPlaceholder;
                end %end while
            end
            % end Function UpdateFrame3 \\
           

%%% Body %%% 

            fprintf("ret = %d camID = %s\n", app.ret, app.camID);
            if(app.ret == 1)
                error("\nERROR: Could not find camera")
            end

            %Set Camera Parameters
            QHY_6_SetLiveBrightness(app.camID, 0.0);
            QHY_6_SetLiveContrast(app.camID, 0.0);
            QHY_6_SetLiveGamma(app.camID, 1.0);
                
            app.ret = QHY_10_BeginLiveExpose(app.camID);
            if(app.ret == 1)
                error("\nERROR: did not begin live expose\n")
            end

            app.ret = QHY_6_SetDDR(app.camID, 1.0);
            if(app.ret == 1)
                error("\nERROR: Could not set DDR\n")
            end

            %Get one image first
            [app.ret, w, h, bpp, channels, ImgData] = QHY_10_GetOneLiveFrame(app.camID);
            if(app.ret == 1)
                error("\nERROR: Failed to get live frame\n")
            end
           
            savingmode = app.ImageSavingOptionDropDown.Value;
            t1 = string(datetime('now','Format','yyyy-MM-dd'));
            t2 = string(datetime('now','Format','HH_mm_ss'));
            lpath = "C:\Users\User\Documents\QHY600-Capture\LiveCapture";
            n1path=fullfile(lpath,t1);
            n2path = fullfile(n1path,t2);
            mkdir(n1path);
            mkdir(n2path);
            fprintf("QHY_10_GetOneLiveFrame returned ret = %d w = %d h = %d\n", app.ret, w, h);
            %imshow(ImgData);

            frameNum = 0;
            lastImage = int16(ImgData);
            app.FrameTakingStatusTextArea.Placeholder = 'Frame 1 Taken...';
            %imshow(lastImage);

             %Capture for live mode : Depends of the Image Saving Option!
             %%% Switch Case %%%
             switch app.livesavingmode
                 case 1
                     txtName = fullfile(n2path,strcat("Capturedata_SaveAll",num2str(frameNum),".txt"));
                     txtFile = fopen(txtName,'wt');


                     fprintf(txtFile,'test\n');
                     fclose(txtFile);
                     imName = fullfile(n2path,strcat("Capture",num2str(frameNum),".fits"));
                     fitswrite(int16(ImgData),imName);

                     %% While loop
                     lastImage = UpdateFrame1(frameNum);

                     app.FrameTakingStatusTextArea.Placeholder = 'Finished';
                 case 2

                     UpdateFrame2(frameNum)

                     app.FrameTakingStatusTextArea.Placeholder = 'Averaging...';
                     avgImgData = mean(ImgData,3);
                     txtName = fullfile(n2path,strcat("Capturedata_Average",num2str(frameNum),".txt"));
                     txtFile = fopen(txtName,'wt');


                     fprintf(txtFile,'test\n');
                     fclose(txtFile);
                     imName = fullfile(n2path,strcat("Capture_Average_of",num2str(frameNum),"frames.fits"));
                     fitswrite(int16(avgImgData),imName);
                     app.FrameTakingStatusTextArea.Placeholder = 'Finished!';
                     lastImage = int16(avgImgData);

                 case 3

                     %NOT WORKING STILL
                     weightFirst = 10*log10(mean(ImgData,'all')/std(ImgData,0,'all'));
                     weights = [weightFirst]; % .len perhaps?
                     ImgData = ImgData*weightFirst;

                     %% While loop
                     UpdateFrame3(frameNum, weights, ImgData)

                     app.FrameTakingStatusTextArea.Placeholder = 'Weighted Averaging...';

                     sumsImg = sum(ImgData,3);
                     sumsWeights = sum(weights);
                     wavgImgData = sumsImg/sumsWeights;
                     txtName = fullfile(n2path,strcat("Capturedata_WeightedAverage",num2str(frameNum),".txt"));
                     txtFile = fopen(txtName,'wt');


                     fprintf(txtFile,'test\n');
                     fclose(txtFile);
                     imName = fullfile(n2path,strcat("Capture_WeightedAverage_of",num2str(frameNum),"frames.fits"));
                     fitswrite(int16(wavgImgData),imName);
                     app.FrameTakingStatusTextArea.Placeholder = 'Finished!';
                     lastImage = int16(wavgImgData);

                 otherwise
                     error("throw error no proper image saving mode \n");
             end

             img_spectum = mean(lastImage); 
             plot(app.UIAxes2,img_spectum);
             QHY_10_StopLiveCapture(app.camID);


             clear QHY_6_SetExposeTime;
             clear QHY_6_SetGain;
             clear QHY_6_SetOffset;
             clear QHY_6_SetLiveBrightness;
             clear QHY_6_SetLiveContrast;
             clear QHY_6_SetLiveGamma;
             clear QHY_6_SetDDR;
             clear QHY_9_BeginLiveExpose;
             clear QHY_9_GetOneLiveFrame;
             clear QHY_9_StopLiveCapture;


        end

        % Value changed function: NumofFrameEditField
        function NumofFrameEditFieldValueChanged(app, event)
            value = app.NumofFrameEditField.Value;
            
        end

        % Value changed function: ImageSavingOptionDropDown
        function ImageSavingOptionDropDownValueChanged(app, event)
            value = app.ImageSavingOptionDropDown.Value;
            
            if strcmp(value,'Save All')
                app.livesavingmode = 1;
            elseif strcmp(value,'Weighted average')
                app.livesavingmode = 3;
            else
                app.livesavingmode = 2;
            end

        end

        % Button pushed function: ExportTempButton
        function ExportTempButtonPushed(app, event)

             t1 = datetime('now','Format','yyyy-MM-dd');
             t2 = datetime('now','Format','HH_mm_ss');
             lpath = "C:\Users\User\Documents\QHY600-Capture\TempExport";
             n1path=fullfile(lpath,string(t1));
             n2path =fullfile(n1path,string(t2));
             mkdir(n1path);
             mkdir(n2path);
             all_temp_new = [['temperature'], app.all_temp];
             all_time_temp_new = [['times'], app.all_time_temp];
             csvName = fullfile(n2path,'temp.csv');
             writematrix([all_temp_new;all_time_temp_new],csvName);
        end

        % Button pushed function: ResetTempButton
        function ResetTempButtonPushed(app, event)
            app.all_temp = [];
            app.all_time_temp = [];
            clearpoints(app.tempLine);
            app.temp_starting_time = datetime('now');

        end

        % Button pushed function: CaptureButton_2
        function CaptureButton_2Pushed(app, event)
             fprintf("ret = %d camID = %s\n", app.ret, app.camID);

            if(app.ret == 0)
                currGain = app.StartValueEditField.Value;
                currExpTime =  app.StartValueusEditField.Value;


                QHY_6_SetLiveBrightness(app.camID, 0.0);
                QHY_6_SetLiveContrast(app.camID, 0.0);
                QHY_6_SetLiveGamma(app.camID, 1.0);
                %FOR NOW -> Only Save all frames
                app.ret = QHY_10_BeginLiveExpose(app.camID);

                if(app.ret == 0)
                    app.ret = QHY_6_SetDDR(app.camID, 1.0);

                    for i = 1:app.NumberofMeasEditField.Value
                        QHY_6_SetGain(app.camID,currGain);
                        QHY_6_SetExposeTime(app.camID, currExpTime);

                        if(app.ret == 0)
                            %Get one image first
                            [app.ret, w, h, bpp, channels, ImgData] = QHY_10_GetOneLiveFrame(app.camID);
                            if(app.ret==0)
                                savingmode = app.ImageSavingOptionDropDown_2.Value;
                                t1 = string(datetime('now','Format','yyyy-MM-dd'));
                                t2 = string(datetime('now','Format','HH_mm_ss'));
                                lpath = "C:\Users\User\Documents\QHY600-Capture\DynamicCapture";
                                n1path=fullfile(lpath,t1);
                                n2path = fullfile(n1path,t2,strcat('Meas',string(i)));
                                mkdir(n1path);
                                mkdir(n2path);
                                fprintf("QHY_10_GetOneLiveFrame returned ret = %d w = %d h = %d\n", app.ret, w, h);
                                %imshow(ImgData);
    
                                frameNum = 0;
                                
                                lastImage = int16(ImgData);
                                
                                if (app.dynamsavingmode == 1)
                                    
                                   
                                    txtName = fullfile(n2path,strcat("Capturedata_SaveAll",num2str(frameNum),".txt"));
                                    txtFile = fopen(txtName,'wt');
                       
                        
                                    fprintf(txtFile,'test\n');
                                    fclose(txtFile);
                                    imName = fullfile(n2path,strcat("Capture",num2str(frameNum),".fits"));
                                    fitswrite(int16(ImgData),imName);
    
                                    while(frameNum < app.NumofFrameperParaEditField.Value)
                                        tic
        
                                        app.ret = QHY_6_SetDDR(app.camID, 1.0);
                                        if(app.ret == 0)
                                            [app.ret, w, h, bpp, channels, ImgData] = QHY_10_GetOneLiveFrame(app.camID);
                                            if(app.ret==0)
                                                fprintf("QHY_10_GetOneLiveFrame returned ret = %d w = %d h = %d\n", app.ret, w, h);
                                                frameNum = frameNum + 1;
                                                %imshow(ImgData);
                                                txtName = fullfile(n2path,strcat("Capturedata",num2str(frameNum),".txt"));
                                                txtFile = fopen(txtName,'wt');
                           
                            
                                                fprintf(txtFile,'test\n');
                                                fclose(txtFile);
                                                imName = fullfile(n2path,strcat("Capture",num2str(frameNum),".fits"));
                                                fitswrite(int16(ImgData),imName);
                                                if frameNum == app.NumofFrameEditField.Value -1
                                                    lastImage = int16(ImgData);
                                                end
                                                
                                            end %end get frame
                                        end %end set DDR
        
                                        toc
                                    end %end while
                                elseif (app.dynamsavingmode == 2)


                                     while(frameNum < app.NumofFrameperParaEditField.Value)
                                        tic
        
                                        app.ret = QHY_6_SetDDR(app.camID, 1.0);
                                        if(app.ret == 0)
                                            [app.ret, w, h, bpp, channels, ImgTemp] = QHY_10_GetOneLiveFrame(app.camID);
                                            if(app.ret==0)
                                                fprintf("QHY_10_GetOneLiveFrame returned ret = %d w = %d h = %d\n", app.ret, w, h);
                                                frameNum = frameNum + 1;
                                                ImgData(:,:,frameNum) = ImgTemp;
                                                
                                            end %end get frame
                                        end %end set DDR
        
                                        toc
                                     end %end while
                                     avgImgData = mean(ImgData,3);
                                     txtName = fullfile(n2path,strcat("Capturedata_Average",num2str(frameNum),".txt"));
                                     txtFile = fopen(txtName,'wt');
                       
                        
                                     fprintf(txtFile,'test\n');
                                     fclose(txtFile);
                                     imName = fullfile(n2path,strcat("Capture_Average_of",num2str(frameNum),"frames.fits"));
                                     fitswrite(int16(avgImgData),imName);
                                     lastImage = int16(avgImgData);
    
                                elseif (app.dynamsavingmode == 3)
                                    
    
                                    %NOT WORKING STILL
                                     while(frameNum < app.NumofFrameperParaEditField.Value)
                                        tic
        
                                        app.ret = QHY_6_SetDDR(app.camID, 1.0);
                                        if(app.ret == 0)
                                            [app.ret, w, h, bpp, channels, ImgTemp] = QHY_10_GetOneLiveFrame(app.camID);
                                            if(app.ret==0)
                                                fprintf("QHY_10_GetOneLiveFrame returned ret = %d w = %d h = %d\n", app.ret, w, h);
                                                frameNum = frameNum + 1;
                                                ImgData(:,:,frameNum) = ImgTemp;
                                                pause(0.01)
                                            end %end get frame
                                        end %end set DDR
        
                                        toc
                                     end %end while
                                     avgImgData = wmean(ImgData,3);
                                     txtName = fullfile(n2path,strcat("Capturedata_WeightedAverage",num2str(frameNum),".txt"));
                                     txtFile = fopen(txtName,'wt');
                       
                        
                                     fprintf(txtFile,'test\n');
                                     fclose(txtFile);
                                     imName = fullfile(n2path,strcat("Capture_WeightedAverage_of",num2str(frameNum),"frames.fits"));
                                     fitswrite(int16(avgImgData),imName);
                                     lastImage = int16(avgImgData);
                                end %end saving mode
                            end %end get live frame
                        end %end set DDR
                        currGain = currGain + app.StepsEditField.Value;
                        currExpTime = currExpTime + app.StepsEditField_2.Value;

                    end %end for num of measurements
                    
                    img_spectum = mean(lastImage);
                    plot(app.UIAxes2,img_spectum);

                    QHY_10_StopLiveCapture(app.camID);
                end %end begin live
            end %end ret

            clear QHY_6_SetExposeTime;
            clear QHY_6_SetGain;
            clear QHY_6_SetOffset;
            clear QHY_6_SetLiveBrightness;
            clear QHY_6_SetLiveContrast;
            clear QHY_6_SetLiveGamma;
            clear QHY_6_SetDDR;
            clear QHY_9_BeginLiveExpose;
            clear QHY_9_GetOneLiveFrame;
            clear QHY_9_StopLiveCapture;

        end

        % Value changed function: ImageSavingOptionDropDown_2
        function ImageSavingOptionDropDown_2ValueChanged(app, event)
            value = app.ImageSavingOptionDropDown_2.Value;
            
            if strcmp(value,'Save All')
                app.dynamsavingmode = 1;
            elseif strcmp(value,'Weighted average')
                app.dynamsavingmode = 3;
            else
                app.dynamsavingmode = 2;
            end

        end

        % Button pushed function: SetROIButton
        function SetROIButtonPushed(app, event)
            startingHeightPosition = app.WidthStartingPostionEditField.Value;
            startingWidthPosition = app.HeightStartingPositionEditField.Value;
            
            heightSize = app.WidthofROIEditField_2.Value;
            widthSize = app.HeightofROIEditField.Value;
           
            %Have to test if this is how it works
            app.ret = QHY_6_SetROI(app.camID, double(startingHeightPosition),double(startingWidthPosition), double(heightSize),double(widthSize));

            if ( not(app.ret == 0))
                app.ConnectionStatusTextArea.Placeholder = 'Error Setting the ROI';
            end

        end

        % Button pushed function: ResetallSettingstoDefaultButton
        function ResetallSettingstoDefaultButtonPushed(app, event)
            %photographic mode
            app.ret = QHY_2_SetReadMode(app.camID,0);
            app.ret = QHY_6_SetROI(app.camID, double(0),double(0), double(6422),double(9600));
            app.ret = QHY_6_SetGain(app.camID,20);
            app.ret = QHY_6_SetExposeTime(app.camID,0);
            app.ret = QHY_6_SetOffset(app.camID,20);
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            %fprintf("meow");
            

        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar2 = uitoolbar(app.UIFigure);
            app.PushTool2 = uipushtool(app.Toolbar2);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature vs. Time')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spectrum Vizualization of Last Frame Taken')
            xlabel(app.UIAxes2, 'pixels width')
            ylabel(app.UIAxes2, 'Intensity')
            zlabel(app.UIAxes2, 'Z')
            app.ConnecttoCameraButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoCameraButtonPushed, true);
            app.ConnecttoCameraButton.Text = 'Connect to Camera';
            app.DisconnectfromCameraButton = uibutton(app.UIFigure, 'push');
            app.DisconnectfromCameraButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectfromCameraButtonPushed, true);
            app.DisconnectfromCameraButton.Text = 'Disconnect from Camera';
            app.ConnectedLampLabel = uilabel(app.UIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.ConnectionStatusTextAreaLabel = uilabel(app.UIFigure);
            app.ConnectionStatusTextAreaLabel.Text = 'Connection Status';
            app.ConnectionStatusTextArea = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetRegionofInterestTab = uitab(app.TabGroup);
            app.SetRegionofInterestTab.Title = 'Set Region of Interest';
            app.RegionOfInterestSetterROILabel = uilabel(app.SetRegionofInterestTab);
            app.RegionOfInterestSetterROILabel.Text = 'Region Of Interest Setter (ROI) ';
            app.WidthStartingPostionEditFieldLabel = uilabel(app.SetRegionofInterestTab);
            app.WidthStartingPostionEditFieldLabel.Text = 'Width Starting Postion';
            app.WidthStartingPostionEditField = uieditfield(app.SetRegionofInterestTab, 'numeric');
            app.WidthofROIEditField_2Label = uilabel(app.SetRegionofInterestTab);
            app.WidthofROIEditField_2Label.Text = 'Width of ROI';
            app.WidthofROIEditField_2 = uieditfield(app.SetRegionofInterestTab, 'numeric');
            app.WidthofROIEditField_2.Value = 4000;
            app.HeightofROIEditFieldLabel = uilabel(app.SetRegionofInterestTab);
            app.HeightofROIEditFieldLabel.Text = 'Height of ROI';
            app.HeightofROIEditField = uieditfield(app.SetRegionofInterestTab, 'numeric');
            app.HeightofROIEditField.Value = 6000;
            app.SetROIButton = uibutton(app.SetRegionofInterestTab, 'push');
            app.SetROIButton.ButtonPushedFcn = createCallbackFcn(app, @SetROIButtonPushed, true);
            app.SetROIButton.Text = 'Set ROI';
            app.HeightStartingPositionEditFieldLabel = uilabel(app.SetRegionofInterestTab);
            app.HeightStartingPositionEditFieldLabel.Text = 'Height Starting Position';
            app.HeightStartingPositionEditField = uieditfield(app.SetRegionofInterestTab, 'numeric');
            app.SingleFrameTab = uitab(app.TabGroup);
            app.SingleFrameTab.Title = 'Single Frame';
            app.GetSingleFrameButton = uibutton(app.SingleFrameTab, 'push');
            app.GetSingleFrameButton.ButtonPushedFcn = createCallbackFcn(app, @GetSingleFrameButtonPushed, true);
            app.GetSingleFrameButton.Text = 'Get Single Frame';
            app.LiveModeTab = uitab(app.TabGroup);
            app.LiveModeTab.Title = 'Live Mode';
            app.NumofFrameLabel = uilabel(app.LiveModeTab);
            app.NumofFrameLabel.Text = 'Num. of Frame        ';
            app.NumofFrameEditField = uieditfield(app.LiveModeTab, 'numeric');
            app.NumofFrameEditField.ValueChangedFcn = createCallbackFcn(app, @NumofFrameEditFieldValueChanged, true);
            app.NumofFrameEditField.Value = 10;
            app.ImageSavingOptionDropDownLabel = uilabel(app.LiveModeTab);
            app.ImageSavingOptionDropDownLabel.Text = 'Image Saving Option';
            app.ImageSavingOptionDropDown = uidropdown(app.LiveModeTab);
            app.ImageSavingOptionDropDown.Items = {'Save All', 'Average', 'Weighted Av.'};
            app.ImageSavingOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageSavingOptionDropDownValueChanged, true);
            app.ImageSavingOptionDropDown.Value = 'Save All';
            app.CaptureButton = uibutton(app.LiveModeTab, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.Image = uiimage(app.LiveModeTab);
            app.DynamicParaModeTab = uitab(app.TabGroup);
            app.DynamicParaModeTab.Title = 'Dynamic Para. Mode';
            app.NumofFrameperParaEditFieldLabel = uilabel(app.DynamicParaModeTab);
            app.NumofFrameperParaEditFieldLabel.Text = 'Num of Frame per Para.';
            app.NumofFrameperParaEditField = uieditfield(app.DynamicParaModeTab, 'numeric');
            app.NumofFrameperParaEditField.Value = 1;
            app.TabGroup2 = uitabgroup(app.DynamicParaModeTab);
            app.GainTab = uitab(app.TabGroup2);
            app.GainTab.Title = 'Gain';
            app.StartValueEditFieldLabel = uilabel(app.GainTab);
            app.StartValueEditFieldLabel.Text = 'Start Value';
            app.StartValueEditField = uieditfield(app.GainTab, 'numeric');
            app.StartValueEditField.Value = 20;
            app.StepsEditFieldLabel = uilabel(app.GainTab);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.GainTab, 'numeric');
            app.StepsEditField.Value = 5;
            app.NumberofMeasEditFieldLabel = uilabel(app.GainTab);
            app.NumberofMeasEditFieldLabel.Text = 'Number of Meas.';
            app.NumberofMeasEditField = uieditfield(app.GainTab, 'numeric');
            app.NumberofMeasEditField.Value = 4;
            app.ExpTimeTab = uitab(app.TabGroup2);
            app.ExpTimeTab.Title = 'Exp. Time';
            app.StartValueusEditFieldLabel = uilabel(app.ExpTimeTab);
            app.StartValueusEditFieldLabel.Text = 'Start Value (us)';
            app.StartValueusEditField = uieditfield(app.ExpTimeTab, 'numeric');
            app.StartValueusEditField.Value = 20;
            app.StepsEditField_2Label = uilabel(app.ExpTimeTab);
            app.StepsEditField_2Label.Text = 'Steps';
            app.StepsEditField_2 = uieditfield(app.ExpTimeTab, 'numeric');
            app.StepsEditField_2.Value = 10000;
            app.NumberofMeasEditField_3Label = uilabel(app.ExpTimeTab);
            app.NumberofMeasEditField_3Label.Text = 'Number of Meas.';
            app.NumberofMeasEditField_3 = uieditfield(app.ExpTimeTab, 'numeric');
            app.NumberofMeasEditField_3.Value = 4;
            app.Tab = uitab(app.TabGroup2);
            app.CaptureButton_2 = uibutton(app.DynamicParaModeTab, 'push');
            app.CaptureButton_2.ButtonPushedFcn = createCallbackFcn(app, @CaptureButton_2Pushed, true);
            app.CaptureButton_2.Text = 'Capture';
            app.ImageSavingOptionDropDown_2Label = uilabel(app.DynamicParaModeTab);
            app.ImageSavingOptionDropDown_2Label.Text = 'Image Saving Option';
            app.ImageSavingOptionDropDown_2 = uidropdown(app.DynamicParaModeTab);
            app.ImageSavingOptionDropDown_2.Items = {'Save All', 'Average', 'Weighted average'};
            app.ImageSavingOptionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ImageSavingOptionDropDown_2ValueChanged, true);
            app.ImageSavingOptionDropDown_2.Value = 'Save All';
            app.ResetSettingsTab = uitab(app.TabGroup);
            app.ResetSettingsTab.Title = 'Reset Settings';
            app.ResetallSettingstoDefaultButton = uibutton(app.ResetSettingsTab, 'push');
            app.ResetallSettingstoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetallSettingstoDefaultButtonPushed, true);
            app.ResetallSettingstoDefaultButton.Text = 'Reset all Settings to Default';
            app.ReadoutModeDropDownLabel = uilabel(app.UIFigure);
            app.ReadoutModeDropDownLabel.Text = 'Readout Mode';
            app.ReadoutModeDropDown = uidropdown(app.UIFigure);
            app.ReadoutModeDropDown.Items = {'Photographic', 'High Gain', 'Fullwell', 'Ext. Fullwell - 2CMS'};
            app.ReadoutModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ReadoutModeDropDownValueChanged, true);
            app.ReadoutModeDropDown.Value = 'Photographic';
            app.ExportTempButton = uibutton(app.UIFigure, 'push');
            app.ExportTempButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTempButtonPushed, true);
            app.ExportTempButton.Text = 'Export Temp';
            app.ResetTempButton = uibutton(app.UIFigure, 'push');
            app.ResetTempButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTempButtonPushed, true);
            app.ResetTempButton.Text = 'Reset Temp';
            app.CurrentTempTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentTempTextAreaLabel.Text = 'Current Temp.';
            app.CurrentTempTextArea = uitextarea(app.UIFigure);
            app.FrameTakingStatusTextAreaLabel = uilabel(app.UIFigure);
            app.FrameTakingStatusTextAreaLabel.Text = 'Frame Taking Status';
            app.FrameTakingStatusTextArea = uitextarea(app.UIFigure);
            app.DebugButton = uibutton(app.UIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.CameraParametersPanel = uipanel(app.UIFigure);
            app.CameraParametersPanel.Title = 'Camera Parameters';
            app.ExposureusEditFieldLabel = uilabel(app.CameraParametersPanel);
            app.ExposureusEditFieldLabel.Text = 'Exposure (us)';
            app.ExposureusEditField = uieditfield(app.CameraParametersPanel, 'numeric');
            app.ExposureusEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureusEditFieldValueChanged, true);
            app.OffsetEditFieldLabel = uilabel(app.CameraParametersPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.CameraParametersPanel, 'numeric');
            app.OffsetEditField.Limits = [0 100];
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.OffsetEditField.Value = 10;
            app.SetTempLabel = uilabel(app.CameraParametersPanel);
            app.SetTempLabel.Text = 'Set Temp.        ';
            app.SetTempEditField = uieditfield(app.CameraParametersPanel, 'numeric');
            app.SetTempEditField.ValueChangedFcn = createCallbackFcn(app, @SetTempEditFieldValueChanged, true);
            app.GainEditFieldLabel = uilabel(app.CameraParametersPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.CameraParametersPanel, 'numeric');
            app.GainEditField.Limits = [0 100];
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QHY600App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end